classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Button                      matlab.ui.control.Button
        GridLayout                  matlab.ui.container.GridLayout
        AutoRunButton               matlab.ui.control.StateButton
        Label                       matlab.ui.control.Label
        LoadOptimalButton           matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        RunButton                   matlab.ui.control.Button
        InvertButton                matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        FillButton                  matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        RISControlPanelButtonGroup  matlab.ui.container.ButtonGroup
        invButton                   matlab.ui.control.ToggleButton
        LocalizationFig             matlab.ui.control.UIAxes
        GainFig                     matlab.ui.control.UIAxes
        ImageDKS                    matlab.ui.control.UIAxes
    end

    % Makes the RIS matrices global variables
    properties (Access = public)
        ButtonMatrix    matlab.ui.control.ToggleButton
        RISActMatrix    logical
        calculateVariables logical
        RIS_config      logical
        h               double
        g               double
        g_sweep         double
        param_new       struct
        receivedSignalAtRx   double
        sweepSignalStrenghts double
        Rx_sweepPoints_coor  double
        scatterPlot     matlab.graphics.chart.primitive.Scatter
        surfacePlot     matlab.graphics.chart.primitive.Surface
        imagePlot       matlab.graphics.primitive.Image
        ImageToSend     uint8
    end
    
    methods (Access = public)
        %% GUI functions
        % Updates the current RIS activation matrix from the button array
        function updateActMatrix(app)
            app.RISActMatrix = zeros(size(app.ButtonMatrix));
            for col = 1:size(app.ButtonMatrix,2)
                for row = 1:size(app.ButtonMatrix,1)
                    app.RISActMatrix(row,col) = app.ButtonMatrix...
                        (row,col).BackgroundColor(1) == 0;                        
                end
            end      
        end
        
        % Sets the button matrix according to the activation matrix
        function setButtonMatrix(app, actMatrix)
            for col = 1:size(app.ButtonMatrix,2)
                for row = 1:size(app.ButtonMatrix,1)
                    if actMatrix(row,col) == 0
                        app.ButtonMatrix(row,col).BackgroundColor = ...
                            [0.9600, 0.9600, 0.9600];
                    else
                        app.ButtonMatrix(row,col).BackgroundColor = ...
                            [0.00, 0.45, 0.74];
                    end
                end
            end            
        end
        %% Simulation functions
        function runFunction(app)
            if app.calculateVariables
                app.calculateVariables = false;
                app.Label.Text = '';
                mydir = pwd;
                addpath([mydir '/optimizationFunctions'])
                addpath([mydir '/RIS_pics'])
                %% params
                
                %RIS properties
                param.RIS_size = 'large_bt';            % variant of RIS
                param.RIS_orientation = 'standing up';  % {'laid_down'} orientation of RIS
                param.N_surfaces_X = 1;                 % number of RIS modules in x-Dir
                param.N_surfaces_Y = 1;                 % number of RIS modules in y-Dir   
                
                %System properties
                param.direct_channel_property = 'blocked'; % horn antenna
                param.frontEdgeOffset = 3.7; % offset between horn antenna front edge and phase center in cm
                param.freq = 5.53 *10^9;      % frequency, at which to optimize for
                
                %Algorithm variables
                param.opt_goal = 'maximize';
                param.codebookPrecision = 1; % codebook precision in deg (~ 10/prec + 140/prec codebookPoints for localizing Tx/Rx)
                
                param.phaseResolution = 4; % how many configurations are compared during tracking
                param.UWB_angle_uncertainty = 2.5; % beam is split into +0.5*uncert deg and -0.5*uncert to cover larger area
                param.UWB_dist_uncertainty = 0.1; % beam is split into +0.5*uncert m and -0.5*uncert m to cover larger area
                %param.debug_initialize = 1;  % generates validation/visualization plots
                param.debug_tracking = 1;    % generates validation/visualization plots
                
                %% system knowledge to improve performance
                %param.estimatedTxDist = 1.5;            % in m, estimated distance between Tx to RIS center  
                % 'left'   = in front of left most RIS column or further, 
                % 'center' = anywhere in front of RIS Wall
                % 'right'  = in front of right most row or further, 
                %param.roughTxAntennaPosition = 'left';  % w.r.t looking at RIS from front (halves the search points for Tx) 
                
                param.Tx_dist = 2;            % in m
                param.Tx_angle = -30;         % in deg
                param.relative_Tx_height = 0; % zAxis offset in m (elevation w.r.t to RIS center for where Rx will be tracked)
                
                param.relative_Rx_height = 0; % zAxis offset in m (elevation w.r.t to RIS center for where Rx will be tracked)
                
                param.estimatedRxDist = 1.9;% distance for generating the channels for the Beamplot
                
                trackingTechniqueInd=1; 
                
                
                %choose one of those with above params
                trackingTechnique{1} = 'focus';
                trackingTechnique{2} = 'split';
                
                param_focus=param;
                param_split=param;


                %UWB = receiver
                UWB_data_position = 2;
                UWB_data_angle    = 20;
                %% to this in while loop of actual measurements
                %for runtime = 0:10 % 10 Messungen in 10 sec
                
                %% ToDo
                % take current UWB values, this emulates movement
                % UWB_data_position = UWB_data_position + rand(1,1);
                % UWB_data_angle    = UWB_data_position + rand(1,1);
                
                switch trackingTechnique {trackingTechniqueInd}
                    case 'focus'
                        param_focus.Rx_dist_UWB        =  UWB_data_position;         % in m
                        param_focus.Rx_angle_UWB_angle =  UWB_data_angle;            % in deg
                        [configSet_focus,param_focus] = trackUser(param_focus, app.LocalizationFig);
                        app.param_new = param_focus;
                        configSet_new = configSet_focus;
                
                    case 'split'
                        param_split.Rx_dist_UWB        =  UWB_data_position;      % in m
                        param_split.Rx_angle_UWB_angle =  UWB_data_angle;         % in deg
                        [configSet_split,param_split] = trackUser_BeamSplit(param_split);
                        app.param_new = param_split;
                        configSet_new = configSet_split;
                
                end
                
                %% visualizing calculated parameters 
                %optimal RIS config
                RIS_OptimalConfig = configSet_new;
                %figure
                %subplot(1,2,1)
                %imagesc((RIS_OptimalConfig>0))
                %           
                %received signal at Rx
                app.h = app.param_new.chan1;
                app.g = app.param_new.chan2;
                theta_16by16_opt = exp(1i*RIS_OptimalConfig);
                thetaVec_opt = reshape(theta_16by16_opt.',[],1);
                userInput = app.RISActMatrix;
                %theta_16by16 = exp(1i*userInput);
                thetaVec = reshape(exp(1i.*pi.*userInput).',[],1);
                
                app.receivedSignalAtRx = pow2db( abs( app.h.'*diag(thetaVec_opt)*app.g )^2 );
                receivedSignalAtRx_current = pow2db( abs( app.h.'*diag(thetaVec)*app.g )^2 );
                %subplot(1,2,2)
                %bar(app.GainFig, receivedSignalAtRx);
                %drawSetup(app.param_new, app.LocalizationFig);
                
                %generateBeampatternByHand
                app.g_sweep = app.param_new.chan2_sweep;
                app.sweepSignalStrenghts = pow2db( abs( app.h.'*diag(thetaVec)*app.g_sweep ).^2 );
                
                app.Rx_sweepPoints_coor = app.param_new.RxSweepPoints;
                %subplot(3,1,3)                     
                app.scatterPlot = scatter3(app.LocalizationFig, app.Rx_sweepPoints_coor(1,:), app.Rx_sweepPoints_coor(2,:), app.Rx_sweepPoints_coor(3,:), 50, app.sweepSignalStrenghts,...
                    'filled','MarkerFaceAlpha', 0.7);
                colorbar(app.LocalizationFig);

                xlabel(app.LocalizationFig,'x')
                ylabel(app.LocalizationFig,'y')
                zlabel(app.LocalizationFig,'z')
                title(app.LocalizationFig,'Signal Strength')
                legend(app.LocalizationFig,'RIS','Rx','RIS element','1st element','Tx')
                %end
                
                drawSetup(app.param_new, app.GainFig);
                numSamples = 1000;
                indices = round(linspace(1, length(app.sweepSignalStrenghts), numSamples));
                sweepSignalStrenghtsSampled = app.sweepSignalStrenghts(indices);
                
                [elevation_mesh, azimuth_mesh] = meshgrid(linspace(-90,90,length(sweepSignalStrenghtsSampled)), ...
                    linspace(0,180,length(sweepSignalStrenghtsSampled)));
                
                sigma_normal = 0.10;
                mu_normal = 0;
                values = linspace(-1,1,length(sweepSignalStrenghtsSampled));
                normal = ((sigma_normal*sqrt(2*pi))^-1)*exp((-1/2)*((values-mu_normal)/sigma_normal).^2);
                normal_norm = normal/max(normal);
                
                power = sweepSignalStrenghtsSampled;
                power = power + abs(min(power(:)));
                power = lowpass(power, 1/(2*numSamples));
                power_norm = meshgrid((power/max(power(:))).^2).*normal_norm';
                power_norm = power_norm*1.7;
                col_scale = power_norm.^2;
                
                z = power_norm .* cosd(elevation_mesh) .* cosd(azimuth_mesh);
                y = -power_norm .* cosd(elevation_mesh) .* sind(azimuth_mesh);
                x = -power_norm .* sind(elevation_mesh);
                
                app.surfacePlot = surf(app.GainFig, x, y, z, col_scale);
                xlabel(app.GainFig,'x')
                ylabel(app.GainFig,'y')
                zlabel(app.GainFig,'z')
                title(app.GainFig,'Beampattern')
                legend(app.GainFig,'RIS','Rx','RIS element','1st element','Tx')
                shading(app.GainFig, 'interp')
                colorbar(app.GainFig);

                app.RIS_config = (RIS_OptimalConfig>0);

                %%Print smiley with noise
                app.ImageToSend = imread('smiley.bmp');
                app.imagePlot = image(app.ImageDKS, uint8(awgn(double(app.ImageToSend), -10.*abs(app.receivedSignalAtRx-receivedSignalAtRx_current))));
                ylim(app.ImageDKS, [0 50])
                xlim(app.ImageDKS, [0 50])

            else 
            %% changing input of RIS
            %for i=2:10
            
                
                UserInput = app.RISActMatrix;
                %app.RIS_config = xor(app.RIS_config, UserInput);
            
                thetaVec = reshape(exp(1i.*pi.*UserInput).',[],1);
                receivedSignalAtRx_current = pow2db( abs( app.h.'*diag(thetaVec)*app.g )^2 );
                app.sweepSignalStrenghts = pow2db( abs( app.h.'*diag(thetaVec)*app.g_sweep ).^2 );
            
                %visualizing
%                 figure(2)
%                 subplot(1,2,1)
%                 imagesc(RIS_config)
%                 colorbar
%             
%                 subplot(1,2,2)
                %bar(app.GainFig, receivedSignalAtRx);
%             
%                 figure(1)
                %hold(app.LocalizationFig, "off")
                %drawSetup(app.param_new, app.LocalizationFig)
                app.scatterPlot.reset;
                app.scatterPlot = scatter3(app.LocalizationFig, app.Rx_sweepPoints_coor(1,:), app.Rx_sweepPoints_coor(2,:), app.Rx_sweepPoints_coor(3,:), 50, app.sweepSignalStrenghts,...
                'filled','MarkerFaceAlpha', 0.7);
                colorbar(app.LocalizationFig);
                xlabel(app.LocalizationFig,'x')
                ylabel(app.LocalizationFig,'y')
                zlabel(app.LocalizationFig,'z')
                title(app.LocalizationFig,'Signal Strength')
                legend(app.LocalizationFig,'RIS','Rx','RIS element','1st element','Tx')
                
                numSamples = 1000;
                indices = round(linspace(1, length(app.sweepSignalStrenghts), numSamples));
                sweepSignalStrenghtsSampled = app.sweepSignalStrenghts(indices);
                
                [elevation_mesh, azimuth_mesh] = meshgrid(linspace(-90,90,length(sweepSignalStrenghtsSampled)), ...
                    linspace(0,180,length(sweepSignalStrenghtsSampled)));
                
                sigma_normal = 0.10;
                mu_normal = 0;
                values = linspace(-1,1,length(sweepSignalStrenghtsSampled));
                normal = ((sigma_normal*sqrt(2*pi))^-1)*exp((-1/2)*((values-mu_normal)/sigma_normal).^2);
                normal_norm = normal/max(normal);
                
                power = sweepSignalStrenghtsSampled;
                power = power + abs(min(power(:)));
                power = lowpass(power, 1/(2*numSamples));
                power_norm = meshgrid((power/max(power(:))).^2).*normal_norm';
                power_norm = power_norm*1.7;
                col_scale = power_norm.^2;
                
                z = power_norm .* cosd(elevation_mesh) .* cosd(azimuth_mesh);
                y = -power_norm .* cosd(elevation_mesh) .* sind(azimuth_mesh);
                x = -power_norm .* sind(elevation_mesh);
                
                delete(app.surfacePlot)
                app.surfacePlot = surf(app.GainFig, x, y, z, col_scale);
                xlabel(app.GainFig,'x')
                ylabel(app.GainFig,'y')
                zlabel(app.GainFig,'z')
                title(app.GainFig,'Beampattern')
                legend(app.GainFig,'RIS','Rx','RIS element','1st element','Tx')
                shading(app.GainFig, 'interp')
                colorbar(app.GainFig);


                %%Print smiley with noise
                app.imagePlot.reset
                app.imagePlot = image(app.ImageDKS, uint8(awgn(double(app.ImageToSend), -10.*abs(app.receivedSignalAtRx-receivedSignalAtRx_current))));
                ylim(app.ImageDKS, [0 50])
                xlim(app.ImageDKS, [0 50])

                pause(0.1)
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% GUI Initialization
            % Code to generate the button matrix            
            numRows = 16;
            numCols = 16;

            btnWidth = 52;%35;
            btnHeigth = 41;%;25;
            btnPadding = 7;%5;

            for col = 1:numCols
                xpos = (col-1)*(btnWidth + btnPadding) + 20;
                for row = 1:numRows
                    ypos = (numRows-row)*(btnHeigth + btnPadding) + 20;
                    app.ButtonMatrix(row,col) = uitogglebutton(app.RISControlPanelButtonGroup,...
                        "Position",[xpos ypos btnWidth btnHeigth],...
                        "Text", strcat(int2str(row),"x",int2str(col)),...
                        "FontSize", 9);  
                    app.ButtonMatrix(row,col).Text = ""; %Comment for num labels
                end
            end
            app.RISActMatrix = false(size(app.ButtonMatrix));
            app.calculateVariables = true;

            set(app.ImageDKS, 'XTickLabel', {}, 'YTickLabel', {})
            %% Simulation
        end

        % Selection changed function: RISControlPanelButtonGroup
        function RISControlPanelButtonGroupSelectionChanged(app, event)
            % Identify the pressed button
            selectedButton = app.RISControlPanelButtonGroup.SelectedObject;

            % Change the background color of the pressed button
            if selectedButton.BackgroundColor == [0.9600, 0.9600, 0.9600]
                selectedButton.BackgroundColor = [0.00,0.45,0.74];
            else
                selectedButton.BackgroundColor = [0.9600, 0.9600, 0.9600];
            end

            % Select invisible Button as default to make every button
            % indefinitely clickable
            selectedButton = app.invButton(1).Value;
            app.invButton.Value = true;
            
            % Generate the current RIS activation matrix
            updateActMatrix(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.RISActMatrix = false(size(app.ButtonMatrix));
            app.setButtonMatrix(app.RISActMatrix);
        end

        % Button pushed function: FillButton
        function FillButtonPushed(app, event)
            app.RISActMatrix = true(size(app.ButtonMatrix));
            app.setButtonMatrix(app.RISActMatrix);
        end

        % Button pushed function: InvertButton
        function InvertButtonPushed(app, event)
            app.RISActMatrix = ~app.RISActMatrix;
            app.setButtonMatrix(app.RISActMatrix);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            userDefinedPattern = app.RISActMatrix;
            uisave("userDefinedPattern", "RIS Pattern");
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            uiopen("RIS Pattern.mat");
            if exist("userDefinedPattern", "var")
                if isa(userDefinedPattern, "logical") && all(size(userDefinedPattern) == size(app.ButtonMatrix))
                    app.RISActMatrix = userDefinedPattern;
                    app.setButtonMatrix(app.RISActMatrix);                    
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            runFunction(app);
        end

        % Button pushed function: LoadOptimalButton
        function LoadOptimalButtonPushed(app, event)
            if(size(app.RISActMatrix) == size(app.RIS_config))
                app.RISActMatrix = app.RIS_config;
                app.setButtonMatrix(app.RISActMatrix);
        
            else
                app.Label.Text = 'Please calculate the optimal configuration with a first RUN.';
            end
        end

        % Callback function: AutoRunButton, LocalizationFig
        function AutoRunButtonValueChanged(app, event)
            counter=0;
            while counter<5%(app.AutoRunButton.Value)
                randomRISActMatrix = rand(16, 16) > 0.95;
                app.RISActMatrix = abs(app.RISActMatrix - randomRISActMatrix);
                app.setButtonMatrix(app.RISActMatrix);
                runFunction(app);
                pause(0.15)
                counter=counter+1;
            end
            app.AutoRunButton.Value=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageDKS = uiaxes(app.GridLayout);
            app.GainFig = uiaxes(app.GridLayout);
            title(app.GainFig, 'Title')
            xlabel(app.GainFig, 'X')
            ylabel(app.GainFig, 'Y')
            zlabel(app.GainFig, 'Z')
            app.LocalizationFig = uiaxes(app.GridLayout);
            title(app.LocalizationFig, 'Title')
            xlabel(app.LocalizationFig, 'X')
            ylabel(app.LocalizationFig, 'Y')
            zlabel(app.LocalizationFig, 'Z')
            app.LocalizationFig.ButtonDownFcn = createCallbackFcn(app, @AutoRunButtonValueChanged, true);
            app.RISControlPanelButtonGroup = uibuttongroup(app.GridLayout);
            app.RISControlPanelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RISControlPanelButtonGroupSelectionChanged, true);
            app.RISControlPanelButtonGroup.Title = 'RIS Control Panel';
            app.invButton = uitogglebutton(app.RISControlPanelButtonGroup);
            app.invButton.Visible = 'off';
            app.invButton.Text = 'Invisible Button';
            app.invButton.Value = true;
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FillButton = uibutton(app.GridLayout, 'push');
            app.FillButton.ButtonPushedFcn = createCallbackFcn(app, @FillButtonPushed, true);
            app.FillButton.Text = 'Fill';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.InvertButton = uibutton(app.GridLayout, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @InvertButtonPushed, true);
            app.InvertButton.Text = 'Invert';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Rub_logo', 'Logo_RUB_BLAU_rgb.jpg');
            app.LoadOptimalButton = uibutton(app.GridLayout, 'push');
            app.LoadOptimalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOptimalButtonPushed, true);
            app.LoadOptimalButton.Text = 'Load Optimal';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.AutoRunButton = uibutton(app.GridLayout, 'state');
            app.AutoRunButton.ValueChangedFcn = createCallbackFcn(app, @AutoRunButtonValueChanged, true);
            app.AutoRunButton.Text = 'Auto Run';
            app.Button = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end